classdef Creditos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        TextArea      matlab.ui.control.TextArea
        Image         matlab.ui.control.Image
        Image2        matlab.ui.control.Image
        VoltarButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            opengl software
            clear;
            clc;
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.5;
            height = screenHeight*0.4;
            drawnow;
            app.UIFigure.Position = [left bottom width height];
        end

        % Button pushed function: VoltarButton
        function VoltarButtonPushed(app, event)
            app.delete;
            Prototipo_1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Interface produzida durante o desenvolvimento do Trabalho Técnico Científico (TTC) do curso de Engenharia de Computação.'; ''; 'Título do Trabalho: Módulo Gerador de Trajetórias Aplicado no Eixo de um Servomecanismo.'; ''; 'Aluno: Gustavo Copini Decol.'; 'Contato: gustavo.copini@gmail.com'; ''; 'Professor Orientador: Alejandro Rafael Garcia Ramirez.'; 'Contato: ramirez@univali.br'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'Robotica.png';
            app.VoltarButton = uibutton(app.UIFigure, 'push');
            app.VoltarButton.ButtonPushedFcn = createCallbackFcn(app, @VoltarButtonPushed, true);
            app.VoltarButton.Text = 'Voltar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Creditos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end