classdef Instrucoes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SobreTextAreaLabel     matlab.ui.control.Label
        SobreTextArea          matlab.ui.control.TextArea
        VoltarButton           matlab.ui.control.Button
        InstruesTextAreaLabel  matlab.ui.control.Label
        InstruesTextArea       matlab.ui.control.TextArea
        Image                  matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            opengl software
            clear;
            clc;
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.5;
            height = screenHeight*0.4;
            drawnow;
            app.UIFigure.Position = [left bottom width height];
        end

        % Button pushed function: VoltarButton
        function VoltarButtonPushed(app, event)
            app.delete;
            Prototipo_1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SobreTextAreaLabel = uilabel(app.UIFigure);
            app.SobreTextAreaLabel.Text = 'Sobre';
            app.SobreTextArea = uitextarea(app.UIFigure);
            app.SobreTextArea.Value = {'A geração de trajetórias consiste em gerar o histórico dos movimentos (variáveis) do eixo ao longo do tempo, como posição, velocidade e aceleração, para que o mesmo cumpra os objetivos de deslocamento desejados (LAGES, 2019)'};
            app.VoltarButton = uibutton(app.UIFigure, 'push');
            app.VoltarButton.ButtonPushedFcn = createCallbackFcn(app, @VoltarButtonPushed, true);
            app.VoltarButton.Text = 'Voltar';
            app.InstruesTextAreaLabel = uilabel(app.UIFigure);
            app.InstruesTextAreaLabel.Text = 'Instruções';
            app.InstruesTextArea = uitextarea(app.UIFigure);
            app.InstruesTextArea.Value = {'Inicialmente escolha o tipo de trajetória a ser gerada. Cada trajetória possui parâmetros que podem ou não ser passados, tornando seus comportamentos distintos. Após preencher os parâmetros (válidos) gere as curvas de posição, velocidade e aceleração caso queira verifica-las antes de enviar para o controlador. Caso o perfil seja satisfatório, envie os parâmetros para que os valores da curva de velocidade sejam repassadas ao sistema físico e posteriormente comparadas as curvas de velocidade.'; ''; 'Para mais informações consulte o livro: Introdução à Robótica - Análise, Controle, Aplicações (Niku)'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'NIKU.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Instrucoes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end