classdef Prototipo_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        buttonPOLY3         matlab.ui.control.Button
        buttonPOLY5         matlab.ui.control.Button
        buttonLSPB          matlab.ui.control.Button
        SairButton          matlab.ui.control.Button
        InterfacedeGeraodeTrajetriasLabel  matlab.ui.control.Label
        InstrucoesButton    matlab.ui.control.Button
        InstrucoesButton_2  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            opengl software
            clear;
            clc;
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.5;
            height = screenHeight*0.4;
            drawnow;
            app.UIFigure.Position = [left bottom width height];
        end

        % Button pushed function: buttonLSPB
        function buttonLSPBButtonPushed(app, event)
            app.delete;
            Trajetoria;
        end

        % Button pushed function: buttonPOLY3
        function buttonPOLY3ButtonPushed(app, event)
            app.delete;
            Trajetoria_pol3
        end

        % Button pushed function: buttonPOLY5
        function buttonPOLY5ButtonPushed(app, event)
            app.delete;
            Trajetoria_pol5;
        end

        % Button pushed function: SairButton
        function SairButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: InstrucoesButton
        function InstrucoesButtonPushed(app, event)
            app.delete;
            Instrucoes;
        end

        % Button pushed function: InstrucoesButton_2
        function InstrucoesButton_2Pushed(app, event)
            app.delete;
            Creditos;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.buttonPOLY3 = uibutton(app.UIFigure, 'push');
            app.buttonPOLY3.ButtonPushedFcn = createCallbackFcn(app, @buttonPOLY3ButtonPushed, true);
            app.buttonPOLY3.Text = 'Polinômio Cúbico';
            app.buttonPOLY5 = uibutton(app.UIFigure, 'push');
            app.buttonPOLY5.ButtonPushedFcn = createCallbackFcn(app, @buttonPOLY5ButtonPushed, true);
            app.buttonPOLY5.Text = {'Polinômio de Quinta '; 'Ordem'};
            app.buttonLSPB = uibutton(app.UIFigure, 'push');
            app.buttonLSPB.ButtonPushedFcn = createCallbackFcn(app, @buttonLSPBButtonPushed, true);
            app.buttonLSPB.Text = {'Segmentos Lineares '; 'com Parábolas'};
            app.SairButton = uibutton(app.UIFigure, 'push');
            app.SairButton.ButtonPushedFcn = createCallbackFcn(app, @SairButtonPushed, true);
            app.SairButton.Text = 'Sair';
            app.InterfacedeGeraodeTrajetriasLabel = uilabel(app.UIFigure);
            app.InterfacedeGeraodeTrajetriasLabel.Text = {'Interface de Geração '; 'de Trajetórias'};
            app.InstrucoesButton = uibutton(app.UIFigure, 'push');
            app.InstrucoesButton.ButtonPushedFcn = createCallbackFcn(app, @InstrucoesButtonPushed, true);
            app.InstrucoesButton.Text = 'Instruções';
            app.InstrucoesButton_2 = uibutton(app.UIFigure, 'push');
            app.InstrucoesButton_2.ButtonPushedFcn = createCallbackFcn(app, @InstrucoesButton_2Pushed, true);
            app.InstrucoesButton_2.Text = 'Créditos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Prototipo_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end