classdef Trajetoria_pol5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        buttonPlot                     matlab.ui.control.Button
        curvasVelocidade               matlab.ui.control.UIAxes
        ParmetrosLabel                 matlab.ui.control.Label
        PolinmiodeQuintaOrdemLabel     matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        curvaPosicao                   matlab.ui.control.UIAxes
        GerarCurvasButton              matlab.ui.control.Button
        Image3                         matlab.ui.control.Image
        curvaVelocidade                matlab.ui.control.UIAxes
        curvaAceleracao                matlab.ui.control.UIAxes
        LaboratriodeRobticaLabel       matlab.ui.control.Label
        Image4                         matlab.ui.control.Image
        VoltaraoMenudeSeleoButton      matlab.ui.control.Button
        LabelEditField                 matlab.ui.control.Label
        edPosInicial                   matlab.ui.control.EditField
        LabelEditField2                matlab.ui.control.Label
        edPosFinal                     matlab.ui.control.EditField
        TempoLabel                     matlab.ui.control.Label
        edTempo                        matlab.ui.control.EditField
        VelocidadeInicialEditFieldLabel  matlab.ui.control.Label
        edVelocidadeInicial            matlab.ui.control.EditField
        VelocidadeFinalEditFieldLabel  matlab.ui.control.Label
        edVelocidadeFinal              matlab.ui.control.EditField
        AceleraoInicialLabel           matlab.ui.control.Label
        edAceleracaoInicial            matlab.ui.control.EditField
        AceleraoFinalLabel             matlab.ui.control.Label
        edAceleracaoFinal              matlab.ui.control.EditField
        VelocidadeMximadoMotorRadsLabel  matlab.ui.control.Label
        edVelocidadeMotor              matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            opengl software
            clear;
            clc;
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.5;
            height = screenHeight*0.4;
            drawnow;
            app.UIFigure.Position = [left bottom width height];
        end

        % Button pushed function: VoltaraoMenudeSeleoButton
        function VoltaraoMenudeSeleoButtonPushed(app, event)
            app.delete;
            Prototipo_1;
        end

        % Button pushed function: GerarCurvasButton
        function GerarCurvasButtonPushed(app, event)
            qi_5 = str2num(app.edPosInicial.Value);
            qf_5 = str2num(app.edPosFinal.Value);
            tf_5 = str2num(app.edTempo.Value);
            vi_5 = str2num(app.edVelocidadeInicial.Value);
            vf_5 = str2num(app.edVelocidadeFinal.Value);
            ai_5 = str2num(app.edAceleracaoInicial.Value);
            af_5 = str2num(app.edAceleracaoFinal.Value);
            
            theta= [qi_5; vi_5;ai_5; qf_5; vf_5;af_5];
            a=[1 0 0 0 0 0; 0 1 0 0 0 0;0 0 2 0 0 0;1 tf_5 tf_5^2 tf_5^3 tf_5^4 tf_5^5;0 1 2*tf_5 3*tf_5^2 4*tf_5^3 5*tf_5^4;0 0 2 6*tf_5 12*tf_5^2 20*tf_5^3];
            c=inv(a)*theta;
            ta=tf_5/100;
            
            for t=0:99
                theta_t(t+1)= c(1)+c(2)*(t*ta)+c(3)*((t*ta)^2)+c(4)*((t*ta)^3)+ (c(5)*(t*ta)^4)+(c(6)*(t*ta)^5);
                v_t(t+1)=c(2)+2*c(3)*(t*ta)+(3*c(4)*(t*ta)^2)+(4*c(5)*(ta*t)^3)+(5*c(6)*(t*ta)^4);
                a_t(t+1)= 2*c(3)+6*c(4)*(t*ta)+(12*c(5)*(t*ta)^2)+(20*c(6)*(t*ta)^3);
            end
            
            % Plots
            g=linspace(0,tf_5,100);
            plot(app.curvaPosicao,g,theta_t);
            plot(app.curvaVelocidade,g,v_t);
            plot(app.curvaAceleracao,g,a_t);
            %plot(app.curvaPosicao,g,theta_t,g,v_t,g,a_t);
            %legend(app.curvaPosicao,'Posição','Velocidade','Aceleração');
        end

        % Button pushed function: buttonPlot
        function buttonPlotButtonPushed(app, event)
            
            delete(instrfindall);
            x=serial('COM3','BAUD',9600);
            
            qi_5 = str2num(app.edPosInicial.Value);
            qf_5 = str2num(app.edPosFinal.Value);
            tf_5 = str2num(app.edTempo.Value);
            vi_5 = str2num(app.edVelocidadeInicial.Value);
            vf_5 = str2num(app.edVelocidadeFinal.Value);
            ai_5 = str2num(app.edAceleracaoInicial.Value);
            af_5 = str2num(app.edAceleracaoFinal.Value);
            
            vel_max_motor = str2num(app.edVelocidadeMotor.Value);;
            vet_velocidades = 0:1:vel_max_motor;
            
            for i=1:vel_max_motor+1
                vet_pwm(i) = round((vet_velocidades(i) - 0)*(255-0)/(vel_max_motor-0) + 0);
            end
            
            theta= [qi_5; vi_5;ai_5; qf_5; vf_5;af_5];
            a=[1 0 0 0 0 0; 0 1 0 0 0 0;0 0 2 0 0 0;1 tf_5 tf_5^2 tf_5^3 tf_5^4 tf_5^5;0 1 2*tf_5 3*tf_5^2 4*tf_5^3 5*tf_5^4;0 0 2 6*tf_5 12*tf_5^2 20*tf_5^3];
            c=inv(a)*theta;
            ta=tf_5/100;
            
            for t=0:99
                theta_t(t+1)= c(1)+c(2)*(t*ta)+c(3)*((t*ta)^2)+c(4)*((t*ta)^3)+ (c(5)*(t*ta)^4)+(c(6)*(t*ta)^5);
                v_t(t+1)=c(2)+2*c(3)*(t*ta)+(3*c(4)*(t*ta)^2)+(4*c(5)*(ta*t)^3)+(5*c(6)*(t*ta)^4);
                a_t(t+1)= 2*c(3)+6*c(4)*(t*ta)+(12*c(5)*(t*ta)^2)+(20*c(6)*(t*ta)^3);
            end
            
            for i=1:100
                v_t(i)=round(v_t(i));
            end
            
            fopen(x);
            pause(2.0)
            fwrite(x,tf_5)
            pause(2.0)
            max(v_t)
            fwrite(x,max(v_t));
            pause(2.0)
            max(vet_pwm(max(v_t)+1))
            fwrite(x,max(vet_pwm(max(v_t)+1)));
            for i=1:100
                fwrite(x,v_t(i));
            end
            pause(2.0)
            for i=1:100
                read_Velocity(i) = fread(x,1,'uint8');
            end
            fclose(x);
            
            % Plots
            g=linspace(0,tf_5,100);
            plot(app.curvasVelocidade,g,v_t,g,read_Velocity);
            legend(app.curvasVelocidade,'Desejada','Real');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Polynomial Fifth-Order';
            app.buttonPlot = uibutton(app.UIFigure, 'push');
            app.buttonPlot.ButtonPushedFcn = createCallbackFcn(app, @buttonPlotButtonPushed, true);
            app.buttonPlot.Text = 'Enviar Parâmetros';
            app.curvasVelocidade = uiaxes(app.UIFigure);
            title(app.curvasVelocidade, 'Curvas de Velocidade')
            xlabel(app.curvasVelocidade, 'Segundos')
            ylabel(app.curvasVelocidade, 'Rad')
            app.ParmetrosLabel = uilabel(app.UIFigure);
            app.ParmetrosLabel.Text = 'Parâmetros';
            app.PolinmiodeQuintaOrdemLabel = uilabel(app.UIFigure);
            app.PolinmiodeQuintaOrdemLabel.Text = 'Polinômio de Quinta Ordem';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.curvaPosicao = uiaxes(app.UIFigure);
            title(app.curvaPosicao, 'Posição')
            xlabel(app.curvaPosicao, '')
            ylabel(app.curvaPosicao, '')
            app.GerarCurvasButton = uibutton(app.UIFigure, 'push');
            app.GerarCurvasButton.ButtonPushedFcn = createCallbackFcn(app, @GerarCurvasButtonPushed, true);
            app.GerarCurvasButton.Text = 'Gerar Curvas';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'usb..png';
            app.curvaVelocidade = uiaxes(app.UIFigure);
            title(app.curvaVelocidade, 'Velocidade')
            xlabel(app.curvaVelocidade, '')
            ylabel(app.curvaVelocidade, '')
            app.curvaAceleracao = uiaxes(app.UIFigure);
            title(app.curvaAceleracao, 'Aceleração')
            xlabel(app.curvaAceleracao, '')
            ylabel(app.curvaAceleracao, '')
            app.LaboratriodeRobticaLabel = uilabel(app.UIFigure);
            app.LaboratriodeRobticaLabel.Text = 'Laboratório de Robótica';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'Robotica.png';
            app.VoltaraoMenudeSeleoButton = uibutton(app.UIFigure, 'push');
            app.VoltaraoMenudeSeleoButton.ButtonPushedFcn = createCallbackFcn(app, @VoltaraoMenudeSeleoButtonPushed, true);
            app.VoltaraoMenudeSeleoButton.Text = 'Voltar ao Menu de Seleção';
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'Posição Inicial';
            app.edPosInicial = uieditfield(app.UIFigure, 'text');
            app.LabelEditField2 = uilabel(app.UIFigure);
            app.LabelEditField2.Text = 'Posição Final';
            app.edPosFinal = uieditfield(app.UIFigure, 'text');
            app.TempoLabel = uilabel(app.UIFigure);
            app.TempoLabel.Text = 'Tempo';
            app.edTempo = uieditfield(app.UIFigure, 'text');
            app.VelocidadeInicialEditFieldLabel = uilabel(app.UIFigure);
            app.VelocidadeInicialEditFieldLabel.Text = 'Velocidade Inicial';
            app.edVelocidadeInicial = uieditfield(app.UIFigure, 'text');
            app.VelocidadeFinalEditFieldLabel = uilabel(app.UIFigure);
            app.VelocidadeFinalEditFieldLabel.Text = 'Velocidade Final';
            app.edVelocidadeFinal = uieditfield(app.UIFigure, 'text');
            app.AceleraoInicialLabel = uilabel(app.UIFigure);
            app.AceleraoInicialLabel.Text = 'Aceleração Inicial';
            app.edAceleracaoInicial = uieditfield(app.UIFigure, 'text');
            app.AceleraoFinalLabel = uilabel(app.UIFigure);
            app.AceleraoFinalLabel.Text = 'Aceleração Final';
            app.edAceleracaoFinal = uieditfield(app.UIFigure, 'text');
            app.VelocidadeMximadoMotorRadsLabel = uilabel(app.UIFigure);
            app.VelocidadeMximadoMotorRadsLabel.Text = {'Velocidade Máxima'; 'do Motor (Rad/s)'};
            app.edVelocidadeMotor = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Trajetoria_pol5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end